classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ObliczonyczasEditField        matlab.ui.control.EditField
        ObliczonyczasEditFieldLabel   matlab.ui.control.Label
        ObliczButton                  matlab.ui.control.Button
        JakchceszobliczyczasDropDown  matlab.ui.control.DropDown
        JakchceszobliczyczasDropDownLabel  matlab.ui.control.Label
        wagaEditField                 matlab.ui.control.EditField
        wagaEditFieldLabel            matlab.ui.control.Label
        gruboEditField                matlab.ui.control.EditField
        gruboEditFieldLabel           matlab.ui.control.Label
        szerokoEditField              matlab.ui.control.EditField
        szerokoEditFieldLabel         matlab.ui.control.Label
        dugoEditField                 matlab.ui.control.EditField
        dugoEditFieldLabel            matlab.ui.control.Label
        PodajwartocistekaLabel        matlab.ui.control.Label
        KalulatorwysmaeniastekaLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ObliczButton
        function ObliczButtonPushed(app, event)
            try

                dlugosc_steka=str2num(app.dugoEditField.Value);
                szerokosc_steka=str2num(app.szerokoEditField.Value);
                grubosc_steka=str2num(app.gruboEditField.Value);
                waga_steka=str2num(app.wagaEditField.Value);
                

%             catch
%                 msgbox("Wpisz tylko liczby");
%                 app.wagaEditField.Value="";
%                 app.dugoEditField.Value="";
%                 app.szerokoEditField.Value="";
%                 app.gruboEditField.Value="";
% 
%             end
%             x=8;
                if (app.JakchceszobliczyczasDropDown.Value=="sieć neuronowa")
                % tu będzie dobra sieć neuronowa
                    input=[dlugosc_steka;szerokosc_steka;grubosc_steka;waga_steka];
                    Dane = readtable('DaneNoweZwiekszoneBardzo.xlsx','Range','A2:F941','ReadVariableNames',false);
                    Dane=table2array(Dane);
                    dlugosc=double(Dane(:,2));
                    szerokosc=double(Dane(:,3));
                    waga=double(Dane(:,4));
                    grubosc=double(Dane(:,5));
                    czas=double(Dane(:,6));
                    inputs = [szerokosc';dlugosc'; grubosc'; waga'];
                    Dane_X = readtable('Zbiór_Testowy.xlsx','Range','A2:F21','ReadVariableNames',false);
                    Dane_X=table2array(Dane_X);
                    dlugosc_X=double(Dane_X(:,2));
                    szerokosc_X=double(Dane_X(:,3));
                    waga_X=double(Dane_X(:,4));
                    grubosc_X=double(Dane_X(:,5));
                    czas_X=double(Dane_X(:,6));
                    % crossvalid
%                     N=length(waga);
%                    cross=crossval('mse',inputs',czas,'Predfun',@regf);
                   %cross=crossvalind('LeaveMOut',N,1);
                    inputs_X = [szerokosc_X';dlugosc_X'; grubosc_X'; waga_X'];
                    targets = czas';
                    net = feedforwardnet([30,25,20,10,9,8,5,5],'trainoss'); %podawanie wektoru %uwzglednienie pola
                    net.trainParam.showWindow = 0;
                    [net,tr] = train(net,inputs,targets);
                    test_sieci=sim(net,inputs_X);
                    y=net(inputs);
                    blad=targets-y;
                    sredni=mse(net,targets,y);
                    wariancja=var(abs(blad));
                    odchylenie=std(abs(blad));
                    output = net(input);


                elseif (app.JakchceszobliczyczasDropDown.Value=="svm")
                    % tu będzie maszyna smv
                    Dane = readtable('DaneNoweZwiekszoneBardzo.xlsx','Range','B2:E961','ReadVariableNames',false);
                    Klasyfikacja=readtable('DaneNoweZwiekszoneBardzo.xlsx','Range','A2:G961','ReadVariableNames',false);
                    klasyfikator=table2array(Klasyfikacja(:,7));
                    Dane=table2array(Dane);
                    SVModel=cell(3,1);
                    classes=unique(klasyfikator);
                    rng(1);
                    for j=1:numel(classes)
                        indx=strcmp(klasyfikator,classes(j));
                        SVModel{j}=fitcsvm(Dane,indx,'ClassNames',[false true],'Standardize',true,...
            'KernelFunction','rbf','BoxConstraint',1);
                    end
                    % scosPredict
                    label=resubPredict(SVModel{1});
                    inputy=[dlugosc_steka;szerokosc_steka;waga_steka;grubosc_steka]';
                    %test?
                    for j=1:numel(classes)
                        [~,score]=predict(SVModel{j},inputy);
                        Scores(:,j)=score(:,2);
                    end
                       
                    [~,maxScore]=max(Scores,[],2);
                    result=maxScore;
                    classes;
                    if result==1
                        output="10-12"
                    elseif result==2
                        output="4-6"
                    else
                        output="7-9"
                    end
                
                %smv=fitcsvm(Dane,klasyfikator);
                end
            app.ObliczonyczasEditField.Value=num2str(output);
            
                catch
                msgbox("Wpisz tylko liczby");
                app.wagaEditField.Value="";
                app.dugoEditField.Value="";
                app.szerokoEditField.Value="";
                app.gruboEditField.Value="";

                end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.KalulatorwysmaeniastekaLabel = uilabel(app.UIFigure);
            app.KalulatorwysmaeniastekaLabel.Text = 'Kalulator wysmażenia steka';
            app.PodajwartocistekaLabel = uilabel(app.UIFigure);
            app.PodajwartocistekaLabel.Text = 'Podaj wartości steka';
            app.dugoEditFieldLabel = uilabel(app.UIFigure);
            app.dugoEditFieldLabel.Text = 'długość';
            app.dugoEditField = uieditfield(app.UIFigure, 'text');
            app.szerokoEditFieldLabel = uilabel(app.UIFigure);
            app.szerokoEditFieldLabel.Text = 'szerokość';
            app.szerokoEditField = uieditfield(app.UIFigure, 'text');
            app.gruboEditFieldLabel = uilabel(app.UIFigure);
            app.gruboEditFieldLabel.Text = 'grubość';
            app.gruboEditField = uieditfield(app.UIFigure, 'text');
            app.wagaEditFieldLabel = uilabel(app.UIFigure);
            app.wagaEditFieldLabel.Text = 'waga';
            app.wagaEditField = uieditfield(app.UIFigure, 'text');
            app.JakchceszobliczyczasDropDownLabel = uilabel(app.UIFigure);
            app.JakchceszobliczyczasDropDownLabel.Text = 'Jak chcesz obliczyć czas?';
            app.JakchceszobliczyczasDropDown = uidropdown(app.UIFigure);
            app.JakchceszobliczyczasDropDown.Items = {'sieć neuronowa', 'svm'};
            app.JakchceszobliczyczasDropDown.Value = 'sieć neuronowa';
            app.ObliczButton = uibutton(app.UIFigure, 'push');
            app.ObliczButton.ButtonPushedFcn = createCallbackFcn(app, @ObliczButtonPushed, true);
            app.ObliczButton.Text = 'Oblicz';
            app.ObliczonyczasEditFieldLabel = uilabel(app.UIFigure);
            app.ObliczonyczasEditFieldLabel.Text = 'Obliczony czas';
            app.ObliczonyczasEditField = uieditfield(app.UIFigure, 'text');
            app.ObliczonyczasEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end